classdef Question6_2_3_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        Button              matlab.ui.control.Button
        HesAPiroteButton_2  matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Label               matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        Button_8            matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        Button_5            matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Button_3            matlab.ui.control.Button
        Button_9            matlab.ui.control.Button
        HesAPiroteButton_3  matlab.ui.control.Button
        MadebyButton        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        a=-2.5 % Description
        feature=1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HesAPiroteButton_2
        function HesAPiroteButton_2Pushed(app, event)

            buttom2(app.UIAxes,app.a,app.feature)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            buttom(app.UIAxes,app.a,app.feature)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            buttom3(app.UIAxes)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
        value = app.DropDown.Value;
        switch value
            case "默认"
                app.a=-2.5;
            case "混响"
                app.a = -1; % 修改 app 属性
            case "跳跃"
                app.a = -5;
            case "杂音"
                app.a = 0;
            otherwise
                app.a= -2.5;
        end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
        switch value
            case "默认"
                app.feature=1;
            case "钢琴"
                app.feature=2; % 修改 app 属性
            case "吉他"
                app.feature=3;
            otherwise
                app.feature=1;
        end
        end

        % Button pushed function: HesAPiroteButton_3
        function HesAPiroteButton_3Pushed(app, event)
            hesAPiroteButton_3Pushed(app.UIAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '《东方红》';
            app.HesAPiroteButton_2 = uibutton(app.GridLayout2, 'push');
            app.HesAPiroteButton_2.ButtonPushedFcn = createCallbackFcn(app, @HesAPiroteButton_2Pushed, true);
            app.HesAPiroteButton_2.Text = {'《加勒比海盗主题曲》'; 'He''s A Pirote'};
            app.Button_4 = uibutton(app.GridLayout2, 'push');
            app.Button_4.Text = '功能列表';
            app.Button_7 = uibutton(app.GridLayout2, 'push');
            app.Button_7.Text = '乐曲选择';
            app.Button_6 = uibutton(app.GridLayout2, 'push');
            app.Button_6.Text = {'特色音乐定制'; '（选择音质音色后点击乐曲）'};
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '音乐质感';
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'默认', '混响', '跳跃', '杂音'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '默认';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '音色';
            app.DropDown_2 = uidropdown(app.GridLayout2);
            app.DropDown_2.Items = {'默认', '吉他', '钢琴'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '默认';
            app.Button_8 = uibutton(app.GridLayout2, 'push');
            app.Button_8.Text = '更多乐曲，敬请期待';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.Button_5 = uibutton(app.GridLayout3, 'push');
            app.Button_5.Text = '乐曲声波显示区';
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, '点击左侧按钮后播放音乐展示波形')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '幅度')
            app.Button_3 = uibutton(app.GridLayout3, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = {'《东方红》'; '吉他特调版'};
            app.Button_9 = uibutton(app.GridLayout3, 'push');
            app.Button_9.Text = {'参数精调'; '乐曲欣赏'; '(右侧乐曲为参数固定版，'; '无需选择音质音色）'; '——>>'; ''};
            app.HesAPiroteButton_3 = uibutton(app.GridLayout3, 'push');
            app.HesAPiroteButton_3.ButtonPushedFcn = createCallbackFcn(app, @HesAPiroteButton_3Pushed, true);
            app.HesAPiroteButton_3.Text = {'He''s A Pirote'; '钢琴特调版'};
            app.MadebyButton = uibutton(app.GridLayout3, 'push');
            app.MadebyButton.Text = {'Made by 谭博文'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Question6_2_3_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end